classdef Cholesterol_Predictor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CholesterolPredictorUIFigure  matlab.ui.Figure
        Button_2                      matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        UIAxes                        matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        Button_4                      matlab.ui.control.Button
        PredictorButton               matlab.ui.control.Button
        Button                        matlab.ui.control.Button
        Button_3                      matlab.ui.control.Button
        ImageNoEditField              matlab.ui.control.NumericEditField
        CholesterolLevelGaugeLabel    matlab.ui.control.Label
        CholesterolLevelGauge         matlab.ui.control.Gauge
        NormalLampLabel               matlab.ui.control.Label
        NormalLamp                    matlab.ui.control.Lamp
        RiskLampLabel                 matlab.ui.control.Label
        RiskLamp                      matlab.ui.control.Lamp
        HighLampLabel                 matlab.ui.control.Label
        HighLamp                      matlab.ui.control.Lamp
        Image                         matlab.ui.control.Image
        CHOLESTEROLPREDICTORLabel     matlab.ui.control.Label
        Image2                        matlab.ui.control.Image
        Button_5                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        prompt   % Description
        n
        a
        e
        cam
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.EditField.Value = 'Read Image/Use Webcam';
            app.CholesterolLevelGauge.ScaleColorLimits = [0 200; 200 240; 240 300];
            app.CholesterolLevelGauge.ScaleColors = {'green' 'yellow' 'red'};
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.n = app.ImageNoEditField.Value;
            disp(app.n)
            imds = imageDatastore("C:\Nikhil_kashyap\IIT JODHPUR  DOCS\Summer Term Project\Camera\eye images","FileExtensions",{'.jpg'});
            if app.n > 0
                app.a = imread(imds.Files{app.n});
                imshow(app.a,'Parent',app.UIAxes);
            else
                app.EditField.Value = 'Try Again';
            end
            close all
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            b = imcrop(app.a);
            imshow(b,'Parent',app.UIAxes2);
            close all
            c = rgb2gray(b);
            d = imresize(c,[660 600]);
%             imshow(d)
            imSz = size(d);
            patchSz = [64 64];
            xIdxs = [1:patchSz(2):imSz(2) imSz(2)+1];
            yIdxs = [1:patchSz(1):imSz(1) imSz(1)+1];
            patches = cell(length(yIdxs)-1,length(xIdxs)-1);
            for i = 1:length(yIdxs)-1
                Isub = d(yIdxs(i):yIdxs(i+1)-1,:,:);
                for j = 1:length(xIdxs)-1
                    patches{i,j} = Isub(:,xIdxs(j):xIdxs(j+1)-1,:);
                end
            end

            app.e = [patches{4,1};patches{5,1};patches{6,1};patches{7,1}...
            ;patches{4,10}';patches{5,10}';patches{6,10}';patches{7,10}'];
            imshow(app.e,'Parent',app.UIAxes3);
        end

        % Button pushed function: PredictorButton
        function PredictCholesterolButtonPushed(app, event)
            offsets = [-2 2];
            glcm = graycomatrix(app.e,"offset",offsets);
            stats = graycoprops(glcm);
            table = struct2table(stats);
            correlation = table.Correlation;
            
            %% Prediction of Cholesterol
            [y_predicted] = prediction(correlation);
            app.CholesterolLevelGauge.Value = cast(y_predicted,'uint16');
            if y_predicted < 200
                app.NormalLamp.Enable = 'on';
            elseif y_predicted >= 200 && y_predicted <= 240
                app.RiskLamp.Enable = 'on';
            else 
                app.HighLamp.Enable = 'on';
            end
        end

        % Callback function
        function ReadImagefromStorageButtonPushed2(app, event)
            app.n = app.ImageNoEditField.Value; 
            imds = imageDatastore("C:\Users\nikhi\OneDrive\Documents\digitalpathology\Camera","FileExtensions",{'.jpg'});
            app.a = imread(imds.Files{app.n});
            imshow(app.a,'Parent',app.UIAxes);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.cam = webcam;
            preview(app.cam);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.a = snapshot(app.cam);
            imshow(app.a,'Parent',app.UIAxes);
            closePreview(app.cam)
        end

        % Close request function: CholesterolPredictorUIFigure
        function CholesterolPredictorUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: ImageNoEditField
        function ImageNoEditFieldValueChanged(app, event)
            value = app.ImageNoEditField.Value;
            
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            close(app.CholesterolPredictorUIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CholesterolPredictorUIFigure = uifigure('Visible', 'off');
            app.CholesterolPredictorUIFigure.Name = 'Cholesterol Predictor';
            app.CholesterolPredictorUIFigure.CloseRequestFcn = createCallbackFcn(app, @CholesterolPredictorUIFigureCloseRequest, true);
            app.Button_2 = uibutton(app.CholesterolPredictorUIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = 'read button.jpg';
            app.Button_2.Text = '';
            app.EditField = uieditfield(app.CholesterolPredictorUIFigure, 'text');
            app.UIAxes = uiaxes(app.CholesterolPredictorUIFigure);
            title(app.UIAxes, 'ORIGINAL IMAGE')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.CholesterolPredictorUIFigure);
            title(app.UIAxes2, 'CROPPED IMAGE')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes3 = uiaxes(app.CholesterolPredictorUIFigure);
            title(app.UIAxes3, 'PATCHED IMAGE')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            app.Button_4 = uibutton(app.CholesterolPredictorUIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Icon = 'crop icon.jpg';
            app.Button_4.Text = '';
            app.PredictorButton = uibutton(app.CholesterolPredictorUIFigure, 'push');
            app.PredictorButton.ButtonPushedFcn = createCallbackFcn(app, @PredictCholesterolButtonPushed, true);
            app.PredictorButton.Icon = 'spinning-beach-ball.gif';
            app.PredictorButton.Text = 'Predictor';
            app.Button = uibutton(app.CholesterolPredictorUIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'webcam.png';
            app.Button.Text = '';
            app.Button_3 = uibutton(app.CholesterolPredictorUIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Icon = '800px_COLOURBOX25863727.jpg';
            app.Button_3.Text = '';
            app.ImageNoEditField = uieditfield(app.CholesterolPredictorUIFigure, 'numeric');
            app.ImageNoEditField.ValueChangedFcn = createCallbackFcn(app, @ImageNoEditFieldValueChanged, true);
            app.CholesterolLevelGaugeLabel = uilabel(app.CholesterolPredictorUIFigure);
            app.CholesterolLevelGaugeLabel.Text = 'Cholesterol Level';
            app.CholesterolLevelGauge = uigauge(app.CholesterolPredictorUIFigure, 'circular');
            app.CholesterolLevelGauge.Limits = [0 300];
            app.NormalLampLabel = uilabel(app.CholesterolPredictorUIFigure);
            app.NormalLampLabel.Text = 'Normal';
            app.NormalLamp = uilamp(app.CholesterolPredictorUIFigure);
            app.NormalLamp.Enable = 'off';
            app.RiskLampLabel = uilabel(app.CholesterolPredictorUIFigure);
            app.RiskLampLabel.Text = 'Risk';
            app.RiskLamp = uilamp(app.CholesterolPredictorUIFigure);
            app.RiskLamp.Enable = 'off';
            app.HighLampLabel = uilabel(app.CholesterolPredictorUIFigure);
            app.HighLampLabel.Text = 'High';
            app.HighLamp = uilamp(app.CholesterolPredictorUIFigure);
            app.HighLamp.Enable = 'off';
            app.Image = uiimage(app.CholesterolPredictorUIFigure);
            app.Image.ImageSource = 'photos icon.png';
            app.CHOLESTEROLPREDICTORLabel = uilabel(app.CholesterolPredictorUIFigure);
            app.CHOLESTEROLPREDICTORLabel.Text = 'CHOLESTEROL PREDICTOR';
            app.Image2 = uiimage(app.CholesterolPredictorUIFigure);
            app.Image2.ImageSource = 'blood-cholesterol iconjpg.jpg';
            app.Button_5 = uibutton(app.CholesterolPredictorUIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Icon = 'exit icon.jpg';
            app.Button_5.Text = '';
            % Show the figure after all components are created
            app.CholesterolPredictorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cholesterol_Predictor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CholesterolPredictorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CholesterolPredictorUIFigure)
        end
    end
end